classdef importLusReqFig_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        CreateanewSimulinkfileCheckBox  matlab.ui.control.CheckBox
        importReqButton                 matlab.ui.control.Button
        importTracButton                matlab.ui.control.Button
        importLusButton                 matlab.ui.control.Button
        traceability_edit               matlab.ui.control.EditField
        lus_edit1                       matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function importLusReqFig_OpeningFcn(app, varargin)
            % --- Executes just before importLusReqFig is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to importLusReqFig (see VARARGIN)
            
            % Choose default command line output for importLusReqFig
            handles.output = hObject;
            
            if strcmp(varargin{1}, 'gcs')
                gcs_path = varargin{2};
            else
                display_msg('USE: importLusReqFig(''gcs'','' path name of the current Simulink system'');',...
                    MsgType.ERROR, 'importLusReqFig', '');
                errordlg('USE: importLusReqFig(''gcs'','' path name of the current Simulink system'');');
                return;
            end
            data = struct('gcs', gcs_path,...
                'lusPath', '', ...
                'mappingPath', '', ...
                'createNewFile',true);
            set(handles.figure1, 'UserData', data);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: importLusButton
        function importLusButton_Callback(app, event)
            % --- Executes on button press in importLusButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to importLusButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data = get(handles.figure1,'UserData');
            [file, p] = uigetfile({'*.lus', '*.lusi'});
            if ischar(file) && ~isempty(file)
                data.lusPath = fullfile(p, file);
                handles.lus_edit1.String = fullfile(p, file);
                set(handles.figure1,'UserData',data);
            end
        end

        % Button pushed function: importReqButton
        function importReqButton_Callback(app, event)
            % --- Executes on button press in importReqButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to importReqButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                data = get(handles.figure1,'UserData');
                importLusReq(data.gcs, data.lusPath, data.mappingPath, data.createNewFile);
            catch me
                display_msg(me.message, MsgType.ERROR, 'importLusReqFig', '');
                display_msg(me.getReport(), MsgType.DEBUG, 'importLusReqFig', '');
            end
            close(handles.figure1)
        end

        % Button pushed function: importTracButton
        function importTracButton_Callback(app, event)
            % --- Executes on button press in importTracButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to importTracButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data = get(handles.figure1,'UserData');
            [file, p] = uigetfile({'*.json'});
            if ischar(file) && ~isempty(file)
                data.mappingPath = fullfile(p, file);
                handles.traceability_edit.String = fullfile(p, file);
                set(handles.figure1,'UserData',data);
            end
        end

        % Value changed function: lus_edit1
        function lus_edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lus_edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lus_edit1 as text
            %        str2double(get(hObject,'String')) returns contents of lus_edit1 as a double
            data = get(handles.figure1,'UserData');
            data.lusPath = get(hObject,'String');
            set(handles.figure1,'UserData',data);
        end

        % Value changed function: traceability_edit
        function traceability_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to traceability_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of traceability_edit as text
            %        str2double(get(hObject,'String')) returns contents of traceability_edit as a double
            data = get(handles.figure1,'UserData');
            data.mappingPath = get(hObject,'String');
            set(handles.figure1,'UserData',data);
        end

        % Value changed function: CreateanewSimulinkfileCheckBox
        function CreateanewSimulinkfileCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            data = get(handles.figure1,'UserData');
            data.createNewFile = app.CreateanewSimulinkfileCheckBox.Value;
            set(handles.figure1,'UserData',data);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'importLusReqFig';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Lustre File:';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Traceability File:';
            app.lus_edit1 = uieditfield(app.figure1, 'text');
            app.lus_edit1.ValueChangedFcn = createCallbackFcn(app, @lus_edit1_Callback, true);
            app.lus_edit1.Tag = 'lus_edit1';
            app.lus_edit1.Value = 'Lustre File Path';
            app.traceability_edit = uieditfield(app.figure1, 'text');
            app.traceability_edit.ValueChangedFcn = createCallbackFcn(app, @traceability_edit_Callback, true);
            app.traceability_edit.Tag = 'traceability_edit';
            app.traceability_edit.Value = 'Traceability File Path';
            app.importLusButton = uibutton(app.figure1, 'push');
            app.importLusButton.ButtonPushedFcn = createCallbackFcn(app, @importLusButton_Callback, true);
            app.importLusButton.Tag = 'importLusButton';
            app.importLusButton.Text = 'Load File';
            app.importTracButton = uibutton(app.figure1, 'push');
            app.importTracButton.ButtonPushedFcn = createCallbackFcn(app, @importTracButton_Callback, true);
            app.importTracButton.Tag = 'importTracButton';
            app.importTracButton.Text = 'Load File';
            app.importReqButton = uibutton(app.figure1, 'push');
            app.importReqButton.ButtonPushedFcn = createCallbackFcn(app, @importReqButton_Callback, true);
            app.importReqButton.Tag = 'importReqButton';
            app.importReqButton.Text = 'Import Requirement';
            app.CreateanewSimulinkfileCheckBox = uicheckbox(app.figure1);
            app.CreateanewSimulinkfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreateanewSimulinkfileCheckBoxValueChanged, true);
            app.CreateanewSimulinkfileCheckBox.Text = 'Create a new Simulink file';
            app.CreateanewSimulinkfileCheckBox.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = importLusReqFig_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)importLusReqFig_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end