classdef importLusTestOblFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateanewSimulinkfileCheckBox  matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CreateaseparateSimulinkfileperrequirementButton  matlab.ui.control.RadioButton
        CreateanewSimulinkfilecontainingallobligationsButton  matlab.ui.control.RadioButton
        importReqButton                 matlab.ui.control.Button
        importTracButton                matlab.ui.control.Button
        importLusButton                 matlab.ui.control.Button
        traceability_edit               matlab.ui.control.EditField
        lus_edit1                       matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.UserData = struct('gcs', gcs,...
                'lusPath', '', ...
                'mappingPath', '', ...
                'createNewFile',true, ...
                'createSeparateFiles',false);            
            
        end

        % Button pushed function: importReqButton
        function importReqButtonPushed(app, event)
            try                
                data = get(app.UIFigure,'UserData');
                if (data.createNewFile)
                    importLusReq(data.gcs, data.lusPath, data.mappingPath, data.createNewFile, data.createSeparateFiles);
                else                    
                    importLusTestObl_separately(data.gcs, data.lusPath, data.mappingPath, data.createNewFile, data.createSeparateFiles);
                end
            catch me
                display_msg(me.message, MsgType.ERROR, 'importLusTestOblFig', 'Something went wrong while importing test obligations.');
                display_msg(me.getReport(), MsgType.DEBUG, 'importLusTestOblFig', 'Something went wrong while importing test obligations.');
            end
            close(app.UIFigure)
        end

        % Button pushed function: importLusButton
        function importLusButtonPushed(app, event)
            data = get(app.UIFigure,'UserData');
            [file, p] = uigetfile({'*.lus', '*.lusi'});
            if ischar(file) && ~isempty(file)
                data.lusPath = fullfile(p, file);
                app.lus_edit1.Value = fullfile(p, file);
                set(app.UIFigure,'UserData',data);
            end
        end

        % Button pushed function: importTracButton
        function importTracButtonPushed(app, event)
            data = get(app.UIFigure,'UserData');
            [file, p] = uigetfile({'*.json'});
            if ischar(file) && ~isempty(file)
                data.mappingPath = fullfile(p, file);
                app.traceability_edit.Value = fullfile(p, file);
                set(app.UIFigure,'UserData',data);
            end
        end

        % Value changed function: CreateanewSimulinkfileCheckBox
        function CreateanewSimulinkfileCheckBoxValueChanged(app, event)
            value = app.CreateanewSimulinkfileCheckBox.Value;
            data = get(app.UIFigure,'UserData');            
            if (value)
                app.ButtonGroup.Enable = "on";
                data.createNewFile = app.CreateanewSimulinkfilecontainingallobligationsButton.Value;
                data.createSeparateFiles = app.CreateaseparateSimulinkfileperrequirementButton.Value;
                set(app.UIFigure,'UserData',data);
            else
                app.ButtonGroup.Enable = "off";
                data.createNewFile = false;
                data.createSeparateFiles = false;
                set(app.UIFigure,'UserData',data);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            data = get(app.UIFigure,'UserData');
            switch selectedButton.Text
                case 'Create a new Simulink file containing all obligations'
                    data.createNewFile = selectedButton.Value;
                    data.createSeparateFiles = ~selectedButton.Value;
                case 'Create a separate Simulink file per obligation (recommended for large obligation sets)'
                    data.createNewFile = ~selectedButton.Value;
                    data.createSeparateFiles = selectedButton.Value;
            end
            set(app.UIFigure,'UserData',data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Lustre File:';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Traceability File:';
            app.lus_edit1 = uieditfield(app.UIFigure, 'text');
            app.lus_edit1.Tag = 'lus_edit1';
            app.lus_edit1.Value = 'Lustre File Path';
            app.traceability_edit = uieditfield(app.UIFigure, 'text');
            app.traceability_edit.Tag = 'traceability_edit';
            app.traceability_edit.Value = 'Traceability File Path';
            app.importLusButton = uibutton(app.UIFigure, 'push');
            app.importLusButton.ButtonPushedFcn = createCallbackFcn(app, @importLusButtonPushed, true);
            app.importLusButton.Tag = 'importLusButton';
            app.importLusButton.Text = 'Load File';
            app.importTracButton = uibutton(app.UIFigure, 'push');
            app.importTracButton.ButtonPushedFcn = createCallbackFcn(app, @importTracButtonPushed, true);
            app.importTracButton.Tag = 'importTracButton';
            app.importTracButton.Text = 'Load File';
            app.importReqButton = uibutton(app.UIFigure, 'push');
            app.importReqButton.ButtonPushedFcn = createCallbackFcn(app, @importReqButtonPushed, true);
            app.importReqButton.Tag = 'importReqButton';
            app.importReqButton.Text = 'Import Test Obligations';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CreateanewSimulinkfilecontainingallobligationsButton = uiradiobutton(app.ButtonGroup);
            app.CreateanewSimulinkfilecontainingallobligationsButton.Text = 'Create a new Simulink file containing all obligations';
            app.CreateanewSimulinkfilecontainingallobligationsButton.Value = true;
            app.CreateaseparateSimulinkfileperrequirementButton = uiradiobutton(app.ButtonGroup);
            app.CreateaseparateSimulinkfileperrequirementButton.Text = 'Create a separate Simulink file per obligation (recommended for large obligation sets)';
            app.CreateanewSimulinkfileCheckBox = uicheckbox(app.UIFigure);
            app.CreateanewSimulinkfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreateanewSimulinkfileCheckBoxValueChanged, true);
            app.CreateanewSimulinkfileCheckBox.Text = 'Create a new Simulink file';
            app.CreateanewSimulinkfileCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = importLusTestOblFig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end