classdef mutation_test_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        uipanel1             matlab.ui.container.Panel
        coverage_percentage  matlab.ui.control.EditField
        max_nb_test          matlab.ui.control.EditField
        text9                matlab.ui.control.Label
        text8                matlab.ui.control.Label
        text7                matlab.ui.control.Label
        text6                matlab.ui.control.Label
        text5                matlab.ui.control.Label
        max                  matlab.ui.control.EditField
        text4                matlab.ui.control.Label
        min                  matlab.ui.control.EditField
        text3                matlab.ui.control.Label
        run                  matlab.ui.control.Button
        makeharness          matlab.ui.control.CheckBox
        save2ws              matlab.ui.control.CheckBox
        text2                matlab.ui.control.Label
        nb_steps             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mutation_test_gui_OpeningFcn(app, varargin)
            % --- Executes just before mutation_test_gui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to mutation_test_gui (see VARARGIN)
            
                % Choose default command line output for mutation_test_gui
                handles.output = hObject;
            
                if strcmp(varargin{1}, 'model_full_path')
                    model_full_path = varargin{2};
                else
                    display_msg('USE: random_test_gui(''model_full_path'',''path_to_model'');',...
                        MsgType.ERROR, 'random_test_gui', '');
                    errordlg('USE: random_test_gui(''model_full_path'',''path_to_model'');');
                    return;
                end
                data = struct('model_full_path', model_full_path,...
                    'nb_steps', 100,...
                    'min', -100, ...
                    'max', 100, ...
                    'max_nb_test', 100,...
                    'min_coverage', 95, ...
                    'export2ws', 1, ...
                    'mkharness', 0);
                set(handles.uipanel1, 'UserData', data);
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes mutation_test_gui wait for user response (see UIRESUME)
                % uiwait(handles.figure1);
        end

        % Value changed function: coverage_percentage
        function coverage_percentage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to coverage_percentage (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of coverage_percentage as text
                %        str2double(get(hObject,'String')) returns contents of coverage_percentage as a double
                data = get(handles.uipanel1,'UserData');
                data.min_coverage =   str2num(get(hObject,'String')) ;
                set(handles.uipanel1,'UserData',data);
        end

        % Value changed function: makeharness
        function makeharness_Callback(app, event)
            % --- Executes on button press in makeharness.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to makeharness (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hint: get(hObject,'Value') returns toggle state of makeharness
                data = get(handles.uipanel1,'UserData');
                data.mkharness =   get(hObject,'Value') ;
                set(handles.uipanel1,'UserData',data);
        end

        % Value changed function: max
        function max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to max (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of max as text
                %        str2double(get(hObject,'String')) returns contents of max as a double
                data = get(handles.uipanel1,'UserData');
                data.max =   str2num(get(hObject,'String')) ;
                set(handles.uipanel1,'UserData',data);
        end

        % Value changed function: max_nb_test
        function max_nb_test_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to max_nb_test (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of max_nb_test as text
                %        str2double(get(hObject,'String')) returns contents of max_nb_test as a double
                data = get(handles.uipanel1,'UserData');
                data.max_nb_test =   str2num(get(hObject,'String')) ;
                set(handles.uipanel1,'UserData',data);
        end

        % Value changed function: min
        function min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to min (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of min as text
                %        str2double(get(hObject,'String')) returns contents of min as a double
                data = get(handles.uipanel1,'UserData');
                data.min =   str2num(get(hObject,'String')) ;
                set(handles.uipanel1,'UserData',data);
        end

        % Value changed function: nb_steps
        function nb_steps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to nb_steps (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of nb_steps as text
                %        str2double(get(hObject,'String')) returns contents of nb_steps as a double
                data = get(handles.uipanel1,'UserData');
                data.nb_steps =  str2double(get(hObject,'String')) ;
                set(handles.uipanel1,'UserData',data);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % --- Executes on button press in run.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to run (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                try
                    data = get(handles.uipanel1,'UserData');
                    mutation_tests( data.model_full_path, ...
                        data.export2ws, ...
                        data.mkharness,...
                        data.nb_steps,...
                        data.min,...
                        data.max,...
                        data.max_nb_test, ...
                        data.min_coverage )
                catch me
                    display_msg(me.message, MsgType.ERROR, 'mutation_test_gui', '');
                    display_msg(me.getReport(), MsgType.DEBUG, 'mutation_test_gui', '');
                end
                try close(handles.figure1), catch,end
        end

        % Value changed function: save2ws
        function save2ws_Callback(app, event)
            % --- Executes on button press in save2ws.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to save2ws (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hint: get(hObject,'Value') returns toggle state of save2ws
                data = get(handles.uipanel1,'UserData');
                data.export2ws =   get(hObject,'Value') ;
                set(handles.uipanel1,'UserData',data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'mutation_test_gui';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'CoCoSim';
            app.uipanel1.Tag = 'uipanel1';
            app.nb_steps = uieditfield(app.uipanel1, 'text');
            app.nb_steps.ValueChangedFcn = createCallbackFcn(app, @nb_steps_Callback, true);
            app.nb_steps.Tag = 'nb_steps';
            app.nb_steps.Value = '100';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Number of steps';
            app.save2ws = uicheckbox(app.uipanel1);
            app.save2ws.ValueChangedFcn = createCallbackFcn(app, @save2ws_Callback, true);
            app.save2ws.Tag = 'save2ws';
            app.save2ws.Text = 'Save Test cases to Workspace';
            app.save2ws.Value = true;
            app.makeharness = uicheckbox(app.uipanel1);
            app.makeharness.ValueChangedFcn = createCallbackFcn(app, @makeharness_Callback, true);
            app.makeharness.Tag = 'makeharness';
            app.makeharness.Text = 'Generate Harness model (test cases in Signal builder)';
            app.run = uibutton(app.uipanel1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'Run';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Min';
            app.min = uieditfield(app.uipanel1, 'text');
            app.min.ValueChangedFcn = createCallbackFcn(app, @min_Callback, true);
            app.min.Tag = 'min';
            app.min.Value = '-100';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Max';
            app.max = uieditfield(app.uipanel1, 'text');
            app.max.ValueChangedFcn = createCallbackFcn(app, @max_Callback, true);
            app.max.Tag = 'max';
            app.max.Value = '100';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'e.g, Min = -100, Min = [-0.3, 15, 10] if you choose to specify a different Min value for each input ';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = '(Number of steps)  * (Simulation step)  =  (Stop time)';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum number of tests should be generated. (Test coverage can be met before this number)';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Maximum number of tests.';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Minimum coverage percentage (%)';
            app.max_nb_test = uieditfield(app.uipanel1, 'text');
            app.max_nb_test.ValueChangedFcn = createCallbackFcn(app, @max_nb_test_Callback, true);
            app.max_nb_test.Tag = 'max_nb_test';
            app.max_nb_test.Value = '100';
            app.coverage_percentage = uieditfield(app.uipanel1, 'text');
            app.coverage_percentage.ValueChangedFcn = createCallbackFcn(app, @coverage_percentage_Callback, true);
            app.coverage_percentage.Tag = 'coverage_percentage';
            app.coverage_percentage.Value = '95';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mutation_test_gui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)mutation_test_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end